classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConvolutionTab                  matlab.ui.container.Tab
        ImageConvolutionMatrixSizeSpinner  matlab.ui.control.Spinner
        MatrixSizeSpinnerLabel          matlab.ui.control.Label
        ImageConvolutionUITable         matlab.ui.control.Table
        ImageConvolutionGoButton        matlab.ui.control.Button
        ImageConvolutionUploadImageButton_2  matlab.ui.control.Button
        ImageConvolutionOutputLabel     matlab.ui.control.Label
        ImageConvolutionInputLabel      matlab.ui.control.Label
        ImageConvolutionOutput          matlab.ui.control.UIAxes
        ImageConvolutionInput           matlab.ui.control.UIAxes
        ImageSmoothingTab               matlab.ui.container.Tab
        ImageSmoothingConvolutionSizeEditField  matlab.ui.control.NumericEditField
        ConvolutionSizeEditFieldLabel   matlab.ui.control.Label
        ImageSmoothingSigmaEditField    matlab.ui.control.NumericEditField
        SigmaLabel                      matlab.ui.control.Label
        ImageSmoothingFilterDropDown    matlab.ui.control.DropDown
        ChooseFilterLabel_3             matlab.ui.control.Label
        ImageSmoothingGoButton          matlab.ui.control.Button
        ImageSmoothingUploadImageButton  matlab.ui.control.Button
        ImageSmoothingOutputLabel       matlab.ui.control.Label
        ImageSmoothingInputLabel        matlab.ui.control.Label
        ImageSmoothingOutput            matlab.ui.control.UIAxes
        ImageSmoothingInput             matlab.ui.control.UIAxes
        FrequencyFilteringTab           matlab.ui.container.Tab
        ImageFrequencyOrderEditField    matlab.ui.control.NumericEditField
        OrderEditFieldLabel_2           matlab.ui.control.Label
        ImageFrequencyCutOffFrequencyEditField  matlab.ui.control.NumericEditField
        CutOffFrequencyEditFieldLabel_2  matlab.ui.control.Label
        ImageFrequencyFilterDropDown    matlab.ui.control.DropDown
        ChooseFilterLabel_2             matlab.ui.control.Label
        ImageFrequencyGoButton          matlab.ui.control.Button
        ImageFrequencyUploadImageButton  matlab.ui.control.Button
        ImageFrequencyOutputLabel       matlab.ui.control.Label
        ImageFrequencyInputLabel        matlab.ui.control.Label
        ImageFrequencyOutput            matlab.ui.control.UIAxes
        ImageFrequencyInput             matlab.ui.control.UIAxes
        ImageBrighteningTab             matlab.ui.container.Tab
        ImageBrighteningAlphaEditField  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        ImageBrighteningOrderEditField  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        ImageBrighteningCutOffFrequencyEditField  matlab.ui.control.NumericEditField
        CutOffFrequencyEditFieldLabel   matlab.ui.control.Label
        ImageBrighteningFilterDropDown  matlab.ui.control.DropDown
        ChooseFilterLabel               matlab.ui.control.Label
        ImageBrighteningGoButton        matlab.ui.control.Button
        ImageBrighteningUploadImageButton  matlab.ui.control.Button
        ImageBrighteningOutputLabel     matlab.ui.control.Label
        ImageBrighteningInputLabel      matlab.ui.control.Label
        ImageBrighteningOutput          matlab.ui.control.UIAxes
        ImageBrighteningInput           matlab.ui.control.UIAxes
        SaltPepperRemovalTab            matlab.ui.container.Tab
        SaltPepperChooseFilterDropDown  matlab.ui.control.DropDown
        ChooseFilterDropDownLabel       matlab.ui.control.Label
        SaltPepperNoisedImageLabel      matlab.ui.control.Label
        SaltPepperOutputLabel           matlab.ui.control.Label
        SaltPepperInputLabel            matlab.ui.control.Label
        SaltPepperGoButton              matlab.ui.control.Button
        AddSaltPepperButton             matlab.ui.control.Button
        SaltPepperUploadImageButton     matlab.ui.control.Button
        ImageSaltPepperOutput           matlab.ui.control.UIAxes
        ImageSaltPepperNoised           matlab.ui.control.UIAxes
        ImageSaltPepperInput            matlab.ui.control.UIAxes
        PeriodicRemovalTab              matlab.ui.container.Tab
        WienerDeconvTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        InputImage
        NoisedImage
        OutputImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageSmoothingUploadImageButton
        function ImageSmoothingUploadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.bmp','All Image Files'};
            [filename, pathname] = uigetfile(filterspec);

            % If the user cancels, return.
            if ~ischar(filename)
                return;
            end

            % Construct the full file path.
            filepath = fullfile(pathname, filename);

            % Read the image.
            imageData = imread(filepath);

            % Show the image.
            imshow(imageData, 'Parent', app.ImageSmoothingInput, 'XData', [1 app.ImageSmoothingInput.Position(3)], 'YData', [1 app.ImageSmoothingInput.Position(4)]);
        end

        % Button pushed function: ImageSmoothingGoButton
        function ImageSmoothingGoButtonPushed(app, event)
            filterName = app.ImageSmoothingFilterDropDown.Value;
            convolutionSize = app.ImageSmoothingConvolutionSizeEditField.Value;
            sigmaValue = app.ImageSmoothingSigmaEditField.Value;

            imageData = getimage(app.ImageSmoothingInput);
            currentWrapper = utils.ImageWrapperFactory.Create(imageData);

            switch filterName
                case 'Mean Filter'
                    % Do computation
                    resultData = currentWrapper.GetMatrixConvolution(utils.SpatialFilter.GenerateMeanFilter(convolutionSize));

                    % Display image
                    imshow(resultData, 'Parent', app.ImageSmoothingOutput, 'XData', [1 app.ImageSmoothingOutput.Position(3)], 'YData', [1 app.ImageSmoothingOutput.Position(4)]);
                case 'Gaussian Filter'
                    % Do computation
                    resultData = currentWrapper.GetMatrixConvolution(utils.SpatialFilter.GenerateGaussianFilter(convolutionSize, sigmaValue));

                    % Display image
                    imshow(resultData, 'Parent', app.ImageSmoothingOutput, 'XData', [1 app.ImageSmoothingOutput.Position(3)], 'YData', [1 app.ImageSmoothingOutput.Position(4)]);
                otherwise
                    uialert(app.UIFigure, 'Unrecognized filter name', 'Filter Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ImageConvolutionInput = uiaxes(app.ConvolutionTab);
            app.ImageConvolutionInput.XTick = [];
            app.ImageConvolutionInput.XTickLabel = {'[ ]'};
            app.ImageConvolutionInput.YTick = [];
            app.ImageConvolutionOutput = uiaxes(app.ConvolutionTab);
            app.ImageConvolutionOutput.XTick = [];
            app.ImageConvolutionOutput.XTickLabel = {'[ ]'};
            app.ImageConvolutionOutput.YTick = [];
            app.ImageConvolutionInputLabel = uilabel(app.ConvolutionTab);
            app.ImageConvolutionInputLabel.Text = 'Input';
            app.ImageConvolutionOutputLabel = uilabel(app.ConvolutionTab);
            app.ImageConvolutionOutputLabel.Text = 'Output';
            app.ImageConvolutionUploadImageButton_2 = uibutton(app.ConvolutionTab, 'push');
            app.ImageConvolutionUploadImageButton_2.Text = 'Upload Image';
            app.ImageConvolutionGoButton = uibutton(app.ConvolutionTab, 'push');
            app.ImageConvolutionGoButton.Text = 'GO!';
            app.ImageConvolutionUITable = uitable(app.ConvolutionTab);
            app.ImageConvolutionUITable.ColumnName = '';
            app.ImageConvolutionUITable.RowName = {};
            app.MatrixSizeSpinnerLabel = uilabel(app.ConvolutionTab);
            app.MatrixSizeSpinnerLabel.Text = 'Matrix Size';
            app.ImageConvolutionMatrixSizeSpinner = uispinner(app.ConvolutionTab);
            app.ImageConvolutionMatrixSizeSpinner.Limits = [0 7];
            app.ImageConvolutionMatrixSizeSpinner.ValueDisplayFormat = '%.0f';
            app.ImageConvolutionMatrixSizeSpinner.Value = 3;
            app.ImageSmoothingTab = uitab(app.TabGroup);
            app.ImageSmoothingTab.Title = 'Image Smoothing';
            app.ImageSmoothingInput = uiaxes(app.ImageSmoothingTab);
            app.ImageSmoothingInput.XTick = [];
            app.ImageSmoothingInput.XTickLabel = {'[ ]'};
            app.ImageSmoothingInput.YTick = [];
            app.ImageSmoothingOutput = uiaxes(app.ImageSmoothingTab);
            app.ImageSmoothingOutput.XTick = [];
            app.ImageSmoothingOutput.XTickLabel = {'[ ]'};
            app.ImageSmoothingOutput.YTick = [];
            app.ImageSmoothingInputLabel = uilabel(app.ImageSmoothingTab);
            app.ImageSmoothingInputLabel.Text = 'Input';
            app.ImageSmoothingOutputLabel = uilabel(app.ImageSmoothingTab);
            app.ImageSmoothingOutputLabel.Text = 'Output';
            app.ImageSmoothingUploadImageButton = uibutton(app.ImageSmoothingTab, 'push');
            app.ImageSmoothingUploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSmoothingUploadImageButtonPushed, true);
            app.ImageSmoothingUploadImageButton.Text = 'Upload Image';
            app.ImageSmoothingGoButton = uibutton(app.ImageSmoothingTab, 'push');
            app.ImageSmoothingGoButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSmoothingGoButtonPushed, true);
            app.ImageSmoothingGoButton.Text = 'GO!';
            app.ChooseFilterLabel_3 = uilabel(app.ImageSmoothingTab);
            app.ChooseFilterLabel_3.Text = 'Choose Filter';
            app.ImageSmoothingFilterDropDown = uidropdown(app.ImageSmoothingTab);
            app.ImageSmoothingFilterDropDown.Items = {'Mean Filter', 'Gaussian Filter'};
            app.ImageSmoothingFilterDropDown.Value = 'Mean Filter';
            app.SigmaLabel = uilabel(app.ImageSmoothingTab);
            app.SigmaLabel.Text = 'Sigma';
            app.ImageSmoothingSigmaEditField = uieditfield(app.ImageSmoothingTab, 'numeric');
            app.ImageSmoothingSigmaEditField.Limits = [0 Inf];
            app.ImageSmoothingSigmaEditField.ValueDisplayFormat = '%.0f';
            app.ConvolutionSizeEditFieldLabel = uilabel(app.ImageSmoothingTab);
            app.ConvolutionSizeEditFieldLabel.Text = 'Convolution Size';
            app.ImageSmoothingConvolutionSizeEditField = uieditfield(app.ImageSmoothingTab, 'numeric');
            app.ImageSmoothingConvolutionSizeEditField.Limits = [1 Inf];
            app.ImageSmoothingConvolutionSizeEditField.ValueDisplayFormat = '%.0f';
            app.ImageSmoothingConvolutionSizeEditField.Value = 1;
            app.FrequencyFilteringTab = uitab(app.TabGroup);
            app.FrequencyFilteringTab.Title = 'Frequency Filtering';
            app.ImageFrequencyInput = uiaxes(app.FrequencyFilteringTab);
            app.ImageFrequencyInput.XTick = [];
            app.ImageFrequencyInput.XTickLabel = {'[ ]'};
            app.ImageFrequencyInput.YTick = [];
            app.ImageFrequencyOutput = uiaxes(app.FrequencyFilteringTab);
            app.ImageFrequencyOutput.XTick = [];
            app.ImageFrequencyOutput.XTickLabel = {'[ ]'};
            app.ImageFrequencyOutput.YTick = [];
            app.ImageFrequencyInputLabel = uilabel(app.FrequencyFilteringTab);
            app.ImageFrequencyInputLabel.Text = 'Input';
            app.ImageFrequencyOutputLabel = uilabel(app.FrequencyFilteringTab);
            app.ImageFrequencyOutputLabel.Text = 'Output';
            app.ImageFrequencyUploadImageButton = uibutton(app.FrequencyFilteringTab, 'push');
            app.ImageFrequencyUploadImageButton.Text = 'Upload Image';
            app.ImageFrequencyGoButton = uibutton(app.FrequencyFilteringTab, 'push');
            app.ImageFrequencyGoButton.Text = 'GO!';
            app.ChooseFilterLabel_2 = uilabel(app.FrequencyFilteringTab);
            app.ChooseFilterLabel_2.Text = 'Choose Filter';
            app.ImageFrequencyFilterDropDown = uidropdown(app.FrequencyFilteringTab);
            app.ImageFrequencyFilterDropDown.Items = {'Low Pass Ideal', 'High Pass Ideal', 'Low Pass Gaussian', 'High Pass Gaussian', 'Low Pass Butterworth', 'High Pass Butterworth'};
            app.ImageFrequencyFilterDropDown.Value = 'Low Pass Ideal';
            app.CutOffFrequencyEditFieldLabel_2 = uilabel(app.FrequencyFilteringTab);
            app.CutOffFrequencyEditFieldLabel_2.Text = 'Cut Off Frequency';
            app.ImageFrequencyCutOffFrequencyEditField = uieditfield(app.FrequencyFilteringTab, 'numeric');
            app.OrderEditFieldLabel_2 = uilabel(app.FrequencyFilteringTab);
            app.OrderEditFieldLabel_2.Text = 'Order';
            app.ImageFrequencyOrderEditField = uieditfield(app.FrequencyFilteringTab, 'numeric');
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.ImageBrighteningInput = uiaxes(app.ImageBrighteningTab);
            app.ImageBrighteningInput.XTick = [];
            app.ImageBrighteningInput.XTickLabel = {'[ ]'};
            app.ImageBrighteningInput.YTick = [];
            app.ImageBrighteningOutput = uiaxes(app.ImageBrighteningTab);
            app.ImageBrighteningOutput.XTick = [];
            app.ImageBrighteningOutput.XTickLabel = {'[ ]'};
            app.ImageBrighteningOutput.YTick = [];
            app.ImageBrighteningInputLabel = uilabel(app.ImageBrighteningTab);
            app.ImageBrighteningInputLabel.Text = 'Input';
            app.ImageBrighteningOutputLabel = uilabel(app.ImageBrighteningTab);
            app.ImageBrighteningOutputLabel.Text = 'Output';
            app.ImageBrighteningUploadImageButton = uibutton(app.ImageBrighteningTab, 'push');
            app.ImageBrighteningUploadImageButton.Text = 'Upload Image';
            app.ImageBrighteningGoButton = uibutton(app.ImageBrighteningTab, 'push');
            app.ImageBrighteningGoButton.Text = 'GO!';
            app.ChooseFilterLabel = uilabel(app.ImageBrighteningTab);
            app.ChooseFilterLabel.Text = 'Choose Filter';
            app.ImageBrighteningFilterDropDown = uidropdown(app.ImageBrighteningTab);
            app.ImageBrighteningFilterDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.ImageBrighteningFilterDropDown.Value = 'Ideal';
            app.CutOffFrequencyEditFieldLabel = uilabel(app.ImageBrighteningTab);
            app.CutOffFrequencyEditFieldLabel.Text = 'Cut Off Frequency';
            app.ImageBrighteningCutOffFrequencyEditField = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.ImageBrighteningTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.ImageBrighteningOrderEditField = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.ImageBrighteningTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.ImageBrighteningAlphaEditField = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.SaltPepperRemovalTab = uitab(app.TabGroup);
            app.SaltPepperRemovalTab.Title = 'Salt & Pepper Removal';
            app.ImageSaltPepperInput = uiaxes(app.SaltPepperRemovalTab);
            app.ImageSaltPepperInput.XTick = [];
            app.ImageSaltPepperInput.XTickLabel = {'[ ]'};
            app.ImageSaltPepperInput.YTick = [];
            app.ImageSaltPepperNoised = uiaxes(app.SaltPepperRemovalTab);
            app.ImageSaltPepperNoised.XTick = [];
            app.ImageSaltPepperNoised.XTickLabel = {'[ ]'};
            app.ImageSaltPepperNoised.YTick = [];
            app.ImageSaltPepperOutput = uiaxes(app.SaltPepperRemovalTab);
            app.ImageSaltPepperOutput.XTick = [];
            app.ImageSaltPepperOutput.XTickLabel = {'[ ]'};
            app.ImageSaltPepperOutput.YTick = [];
            app.SaltPepperUploadImageButton = uibutton(app.SaltPepperRemovalTab, 'push');
            app.SaltPepperUploadImageButton.Text = 'Upload Image';
            app.AddSaltPepperButton = uibutton(app.SaltPepperRemovalTab, 'push');
            app.AddSaltPepperButton.Text = 'Add Noise';
            app.SaltPepperGoButton = uibutton(app.SaltPepperRemovalTab, 'push');
            app.SaltPepperGoButton.Text = 'GO!';
            app.SaltPepperInputLabel = uilabel(app.SaltPepperRemovalTab);
            app.SaltPepperInputLabel.Text = 'Input';
            app.SaltPepperOutputLabel = uilabel(app.SaltPepperRemovalTab);
            app.SaltPepperOutputLabel.Text = 'Output';
            app.SaltPepperNoisedImageLabel = uilabel(app.SaltPepperRemovalTab);
            app.SaltPepperNoisedImageLabel.Text = 'Noised Image';
            app.ChooseFilterDropDownLabel = uilabel(app.SaltPepperRemovalTab);
            app.ChooseFilterDropDownLabel.Text = 'Choose Filter';
            app.SaltPepperChooseFilterDropDown = uidropdown(app.SaltPepperRemovalTab);
            app.PeriodicRemovalTab = uitab(app.TabGroup);
            app.PeriodicRemovalTab.Title = 'Periodic Removal';
            app.WienerDeconvTab = uitab(app.TabGroup);
            app.WienerDeconvTab.Title = 'Wiener Deconv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end